classdef Track_Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DataSelectionPanel             matlab.ui.container.Panel
        PlotDataButton                 matlab.ui.control.Button
        FramesSliderLabel              matlab.ui.control.Label
        FramesSlider                   matlab.ui.control.Slider
        DistanceSliderLabel            matlab.ui.control.Label
        DistanceSlider                 matlab.ui.control.Slider
        XMaxSpinnerLabel               matlab.ui.control.Label
        XMaxSpinner                    matlab.ui.control.Spinner
        YMaxSpinnerLabel               matlab.ui.control.Label
        YMaxSpinner                    matlab.ui.control.Spinner
        ZMaxSpinnerLabel               matlab.ui.control.Label
        ZMaxSpinner                    matlab.ui.control.Spinner
        XMinSpinnerLabel               matlab.ui.control.Label
        XMinSpinner                    matlab.ui.control.Spinner
        YMinSpinnerLabel               matlab.ui.control.Label
        YMinSpinner                    matlab.ui.control.Spinner
        ZMinSpinnerLabel               matlab.ui.control.Label
        ZMinSpinner                    matlab.ui.control.Spinner
        CombineTrackParametersLabel    matlab.ui.control.Label
        SelectDataButton               matlab.ui.control.Button
        TracksCombinedLabel            matlab.ui.control.Label
        numCombinedLabel               matlab.ui.control.Label
        FilterNoiseThresholdCheckBox   matlab.ui.control.CheckBox
        noiseThreshold                 matlab.ui.control.NumericEditField
        SelectedTrackInformationPanel  matlab.ui.container.Panel
        TrackIndexEditFieldLabel       matlab.ui.control.Label
        TrackIndexEditField            matlab.ui.control.EditField
        StartFrameEditFieldLabel       matlab.ui.control.Label
        StartFrameEditField            matlab.ui.control.EditField
        EndFrameEditFieldLabel         matlab.ui.control.Label
        EndFrameEditField              matlab.ui.control.EditField
        ResetButton                    matlab.ui.control.Button
        runningLamp                    matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data   
    end
    
    methods (Access = private)
        
%         function results = func(app)
%             %collect all data
%             allLines = findobj(app.UIFigure,'Type','Line');
% 
%             % This is the set of data you clicked %
%             thisLine = get(info,'Target');
%     
%             % De-emphasize all data %
%             arrayfun( @(x) set(x,'MarkerSize',2), allLines );
% 
%             % Emphasize your data %
%             set(thisLine,'MarkerSize',7);
%     
%             % Find relevant track data %
%             trackIdx = str2double(thisLine.Tag);
%             theData = inputData(trackIdx); % index of track
%     
%             x = info.Position(1); %pointer x-coordinate: cell loc x
%             y = info.Position(2); %pointer y-coordinate: cell loc y
%             frame = info.Position(3); %pointer z-coordinate: relevant frame
%             
%             app.TrackIndexEditField.Value = trackIdx;
%             app.StartFrameEditField.Value = theData.Initial;
%             app.EndFrameEditField.Value = theData.Initial;
%         end
        
        function filterData(app, TrackInfo, threshold)
            app.runningLamp.Color = 'red';
            drawnow

            app.data = TrackInfo;
            hold(app.UIAxes,'on')
            for i = 1:size(TrackInfo, 2) %for each track
                plot3(app.UIAxes, TrackInfo(i).X, TrackInfo(i).Y, TrackInfo(i).Z,'-o','MarkerSize',2, 'Tag', num2str(i))
            end
            hold(app.UIAxes,'off')
            app.runningLamp.Color = 'green';
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          load('lab_demo.mat', "TrackInfo");
          app.data = TrackInfo;
          plot3(app.UIAxes, TrackInfo(1).X, TrackInfo(1).Y, TrackInfo(1).Z,'-o','MarkerSize',2, 'Tag', num2str(1));
          hold(app.UIAxes,'on')
          for i = 2:size(TrackInfo, 2) %for each track
              plot3(app.UIAxes, TrackInfo(i).X, TrackInfo(i).Y, TrackInfo(i).Z,'-o','MarkerSize',2, 'Tag', num2str(i));
          end
             hold(app.UIAxes,'off')
            app.XMinSpinner.Value = app.UIAxes.XLim(1);
            app.XMaxSpinner.Value = app.UIAxes.XLim(2);
            app.YMinSpinner.Value = app.UIAxes.YLim(1);
            app.YMaxSpinner.Value = app.UIAxes.YLim(2);
            app.ZMinSpinner.Value= app.UIAxes.ZLim(1);
            app.ZMaxSpinner.Value = app.UIAxes.ZLim(2);
            
%             datacursormode on;
%             dcm_obj = datacursormode(app.UIFigure);
%             set(dcm_obj,'UpdateFcn',{@displayTrackData, TrackInfo});
        end

        % Value changed function: XMinSpinner
        function XMinSpinnerValueChanged(app, event)
            
  
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            app.runningLamp.Color = 'red';
            drawnow;
            [test_out,combined] = reverse_gap_close(app.data, app.FramesSlider.Value, app.DistanceSlider.Value);
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')
            for i = 1:numel(test_out) %for each track
                if test_out(i).show == 1
                    plot3(app.UIAxes,test_out(i).X, test_out(i).Y, test_out(i).Z,'-o','MarkerSize',2, 'Tag', num2str(i))
                end
            end
            hold(app.UIAxes, 'off')
            app.runningLamp.Color = 'green';
            drawnow;    
            app.numCombinedLabel.Text = string(combined) + ' (' + string(round(combined/numel(test_out)*100)) + '%)';
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
%             app.XMinSpinner.Value = app.UIAxes.XLim(1);
%             app.XMaxSpinner.Value = app.UIAxes.XLim(2);
%             app.YMinSpinner.Value = app.UIAxes.YLim(1);
%             app.YMaxSpinner.Value = app.UIAxes.YLim(2);
%             app.ZMinSpinner.Value = app.UIAxes.ZLim(1);
%             app.ZMaxSpinner.Value = app.UIAxes.ZLim(2);         
        end

        % Value changed function: TrackIndexEditField
        function TrackIndexEditFieldValueChanged(app, event)

            
        end

        % Value changed function: StartFrameEditField
        function StartFrameEditFieldValueChanged(app, event)
            value = app.StartFrameEditField.Value;
            
        end

        % Value changed function: EndFrameEditField
        function EndFrameEditFieldValueChanged(app, event)
            value = app.EndFrameEditField.Value;
            
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            app.runningLamp.Color = 'red';
            drawnow
            if app.FilterNoiseThresholdCheckBox.Value == true
                cla(app.UIAxes)
                out_tracks = filter_noise(app.data, app.noiseThreshold.Value);
                hold(app.UIAxes,'on')
                for i = 1:size(out_tracks, 2) %for each track
                plot3(app.UIAxes, out_tracks(i).X, out_tracks(i).Y, out_tracks(i).Z,'-o','MarkerSize',2, 'Tag', num2str(i))
                end
                hold(app.UIAxes,'off')
                app.data = out_tracks;
            end
            
            app.UIAxes.XLim = [app.XMinSpinner.Value app.XMaxSpinner.Value];
            app.UIAxes.YLim = [app.YMinSpinner.Value app.YMaxSpinner.Value];
            app.UIAxes.ZLim = [app.ZMinSpinner.Value app.ZMaxSpinner.Value];
            
            app.runningLamp.Color = 'green';
            drawnow
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.runningLamp.Color = 'red';
            drawnow
            runStartupFcn(app, @startupFcn)
            app.runningLamp.Color = 'green';
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataSelectionPanel = uipanel(app.UIFigure);
            app.DataSelectionPanel.Title = 'Data Selection';
            app.PlotDataButton = uibutton(app.DataSelectionPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.FramesSliderLabel = uilabel(app.DataSelectionPanel);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.DataSelectionPanel);
            app.FramesSlider.Limits = [0 200];
            app.DistanceSliderLabel = uilabel(app.DataSelectionPanel);
            app.DistanceSliderLabel.Text = 'Distance';
            app.DistanceSlider = uislider(app.DataSelectionPanel);
            app.DistanceSlider.Limits = [0 15];
            app.XMaxSpinnerLabel = uilabel(app.DataSelectionPanel);
            app.XMaxSpinnerLabel.Text = 'X Max';
            app.XMaxSpinner = uispinner(app.DataSelectionPanel);
            app.YMaxSpinnerLabel = uilabel(app.DataSelectionPanel);
            app.YMaxSpinnerLabel.Text = 'Y Max';
            app.YMaxSpinner = uispinner(app.DataSelectionPanel);
            app.ZMaxSpinnerLabel = uilabel(app.DataSelectionPanel);
            app.ZMaxSpinnerLabel.Text = 'Z Max';
            app.ZMaxSpinner = uispinner(app.DataSelectionPanel);
            app.XMinSpinnerLabel = uilabel(app.DataSelectionPanel);
            app.XMinSpinnerLabel.Text = 'X Min';
            app.XMinSpinner = uispinner(app.DataSelectionPanel);
            app.XMinSpinner.ValueChangedFcn = createCallbackFcn(app, @XMinSpinnerValueChanged, true);
            app.YMinSpinnerLabel = uilabel(app.DataSelectionPanel);
            app.YMinSpinnerLabel.Text = 'Y Min';
            app.YMinSpinner = uispinner(app.DataSelectionPanel);
            app.ZMinSpinnerLabel = uilabel(app.DataSelectionPanel);
            app.ZMinSpinnerLabel.Text = 'Z Min';
            app.ZMinSpinner = uispinner(app.DataSelectionPanel);
            app.CombineTrackParametersLabel = uilabel(app.DataSelectionPanel);
            app.CombineTrackParametersLabel.Text = 'Combine Track Parameters';
            app.SelectDataButton = uibutton(app.DataSelectionPanel, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.TracksCombinedLabel = uilabel(app.DataSelectionPanel);
            app.TracksCombinedLabel.Text = 'Tracks Combined:';
            app.numCombinedLabel = uilabel(app.DataSelectionPanel);
            app.numCombinedLabel.Text = '0';
            app.FilterNoiseThresholdCheckBox = uicheckbox(app.DataSelectionPanel);
            app.FilterNoiseThresholdCheckBox.Text = 'Filter Noise: Threshold =';
            app.noiseThreshold = uieditfield(app.DataSelectionPanel, 'numeric');
            app.SelectedTrackInformationPanel = uipanel(app.UIFigure);
            app.SelectedTrackInformationPanel.Title = 'Selected Track Information';
            app.TrackIndexEditFieldLabel = uilabel(app.SelectedTrackInformationPanel);
            app.TrackIndexEditFieldLabel.Text = 'Track Index';
            app.TrackIndexEditField = uieditfield(app.SelectedTrackInformationPanel, 'text');
            app.TrackIndexEditField.ValueChangedFcn = createCallbackFcn(app, @TrackIndexEditFieldValueChanged, true);
            app.StartFrameEditFieldLabel = uilabel(app.SelectedTrackInformationPanel);
            app.StartFrameEditFieldLabel.Text = 'Start Frame';
            app.StartFrameEditField = uieditfield(app.SelectedTrackInformationPanel, 'text');
            app.StartFrameEditField.ValueChangedFcn = createCallbackFcn(app, @StartFrameEditFieldValueChanged, true);
            app.EndFrameEditFieldLabel = uilabel(app.SelectedTrackInformationPanel);
            app.EndFrameEditFieldLabel.Text = 'End Frame';
            app.EndFrameEditField = uieditfield(app.SelectedTrackInformationPanel, 'text');
            app.EndFrameEditField.ValueChangedFcn = createCallbackFcn(app, @EndFrameEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.runningLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Track Visualization')
            xlabel(app.UIAxes, 'Cell Location: X-Coordinate')
            ylabel(app.UIAxes, 'Cell Location: Y-Coordinate')
            zlabel(app.UIAxes, 'Frame')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Track_Visualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end